classdef RoboApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PenHeldCheckBox                matlab.ui.control.CheckBox
        TraceCheckBox                  matlab.ui.control.CheckBox
        Label                          matlab.ui.control.NumericEditField
        NoofPointsEditField            matlab.ui.control.NumericEditField
        NoofPointsEditFieldLabel       matlab.ui.control.Label
        Theta1Theta2Theta3Theta4Label  matlab.ui.control.Label
        PositiveX2RootCheckBox         matlab.ui.control.CheckBox
        PositiveS3RootCheckBox         matlab.ui.control.CheckBox
        MoveToButton                   matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        DisplayT1FrameCheckBox         matlab.ui.control.CheckBox
        AxisScaleEditField             matlab.ui.control.NumericEditField
        AxisScaleEditFieldLabel        matlab.ui.control.Label
        FrameScaleEditField            matlab.ui.control.NumericEditField
        FrameScaleEditFieldLabel       matlab.ui.control.Label
        PhiEditField                   matlab.ui.control.NumericEditField
        PhiEditFieldLabel              matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robotPlot matlab.graphics.chart.primitive.Line
        tracePlot matlab.graphics.chart.primitive.Line
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            axisScale = app.AxisScaleEditField.Value;
            title(app.UIAxes, '4-DOF Robot Arm');
            grid(app.UIAxes, "on");
            axis(app.UIAxes, "equal")
            axis(app.UIAxes, [-axisScale axisScale -axisScale axisScale -axisScale axisScale]);
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            zlabel(app.UIAxes,'Z');
            view(app.UIAxes, 3);
            hold(app.UIAxes,"off");
            rotate3d(app.UIAxes,"on");
        end

        % Callback function: MoveToButton, UIAxes
        function MovetoButtonPushed(app, event)
            %% Inputs from Fields
            
            % Getting inputs in
            xCurr = app.robotPlot.XData(6);
            yCurr = app.robotPlot.YData(6);
            zCurr = app.robotPlot.ZData(6);
            xEnd = app.XEditField.Value;
            yEnd = app.YEditField.Value;
            zEnd = app.ZEditField.Value;
            phiStart = deg2rad(app.Label.Value);
            phiEnd = deg2rad(app.PhiEditField.Value);
            numPoints = app.NoofPointsEditField.Value;
            xPoints = linspace(xCurr, xEnd, numPoints);
            yPoints = linspace(yCurr, yEnd, numPoints);
            zPoints = linspace(zCurr, zEnd, numPoints);
            phiPoints = linspace(phiStart, phiEnd, numPoints);
            traceOn = app.TraceCheckBox.Value;
            penHeld = app.PenHeldCheckBox.Value;

            %% Constants Set-Up

            % Link Offset for z-axis (in cm)
            d1 = 7.7;

            % Link Length for x-axis (in cm)
            a2 = 13;
            a3 = 12.4;
            a4 = 12.6;
            if penHeld
                a4 = 25;
            end

            % Square root indicators
            s3PositiveRoot = app.PositiveS3RootCheckBox.Value; % Set to false for negative output of square root
            x2PositiveRoot = app.PositiveX2RootCheckBox.Value; % Set to false for negative output of square root

            % Graph Visualisers
            frameScale = app.FrameScaleEditField.Value;
            removeT1 = ~(app.DisplayT1FrameCheckBox.Value); % Set to false if you want to keep T1 label and frame

            armPosTotal = cell(1, numPoints);
            for j = 1:numPoints

                % Joint angles for 1DOF (in radians)
                theta1 = atan2(yPoints(j), xPoints(j));

                % 3DOF inputs
                x2 = (x2PositiveRoot*2-1) * sqrt(xPoints(j)^2+yPoints(j)^2);
                z2 = zPoints(j)-d1;

                % Intermediate calculations
                c3 = ((x2-a4*cos(phiPoints(j)))^2 + (z2-a4*sin(phiPoints(j)))^2 - a3^2 - a2^2)/(2*a3*a2);

                % Conditional to ensure that c3 stays in bound
                if c3 > 1
                    c3 = 1;
                end

                % Rest of the calculations
                s3 = (s3PositiveRoot*2-1) * sqrt(1-(c3^2));
                xbar = x2 - (a4*cos(phiPoints(j)));
                zbar = z2 - (a4*sin(phiPoints(j)));
                k2 = a3*s3;
                k1 = a2 + a3*c3;

                % Joint angles for 3DOF (in radians)
                theta2 = atan2(zbar, xbar) - atan2(k2, k1);
                theta3 = atan2(s3, c3);
                theta4 = phiPoints(j)-theta3-theta2;

                % Transformation Matrices
                T = cell(1, 5);
                T{1} = [cos(theta1), -sin(theta1), 0, 0; sin(theta1), cos(theta1), 0, 0; 0, 0, 1, d1; 0, 0, 0, 1];
                T{2} = [cos(theta2), -sin(theta2), 0, 0; 0, 0, -1, 0; sin(theta2), cos(theta2), 0, 0; 0, 0, 0, 1];
                T{3} = [cos(theta3), -sin(theta3), 0, a2; sin(theta3), cos(theta3), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];
                T{4} = [cos(theta4), -sin(theta4), 0, a3; sin(theta4), cos(theta4), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];
                T{5} = [1, 0, 0, a4; 0, 1, 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];

                % Compute arm positions
                positions = cell(1, 5);
                positions{1} = [1, 0, 0, 0; 0, 1, 0, 0; 0, 0, 1, 0; 0, 0, 0, 1]; % Base case: T0
                positions{2} = T{1};
                positions{3} = T{1}*T{2};
                positions{4} = T{1}*T{2}*T{3};
                positions{5} = T{1}*T{2}*T{3}*T{4};
                positions{6} = T{1}*T{2}*T{3}*T{4}*T{5};

                %% Plotting the Robot

                % Getting all of the Positions of the arms
                armPos = zeros(3, 6); % Six points: Base + Four joints + End effector
                for i = 1:6 % 6 positions
                    armPos(:, i) = positions{i}(1:3, end);
                end

                % Setting armPosTotal to store everything before plot all
                armPosTotal{j} = armPos;
            end

            for j = 1:numPoints
                armPos = armPosTotal{j};

                % Deleting the texts and lines
                delete(setdiff(findobj(app.UIAxes, "Type", "line"), [app.robotPlot, app.tracePlot]));
                delete(setdiff(findobj(app.UIAxes, "Type", "text"), [app.robotPlot, app.tracePlot]));

                %Plotting itself
                app.robotPlot.XData = armPosTotal{j}(1, :);
                app.robotPlot.YData = armPosTotal{j}(2, :);
                app.robotPlot.ZData = armPosTotal{j}(3, :);
                if traceOn
                    endEffector = armPos(:, 6);
                    existingX = app.tracePlot.XData;
                    existingY = app.tracePlot.YData;
                    existingZ = app.tracePlot.ZData;
                    set(app.tracePlot, 'XData', [existingX, endEffector(1)], 'YData', [existingY, endEffector(2)], 'ZData', [existingZ, endEffector(3)]);
                end
                drawnow;

                if j==numPoints && ~(traceOn)
                    % Plotting the Frames and labels
                    for i = 1:6

                        % Conditional to remove T1 label and frame, its in flag
                        if i==2 && removeT1
                            continue;
                        end

                        % Adding labels
                        label = sprintf('T%d', (i-1));
                        text(app.UIAxes, armPos(1,i), armPos(2,i), armPos(3,i), label, "VerticalAlignment","top", "HorizontalAlignment","left", "Color","k","FontSize",15)

                        % X-axis Frames
                        xFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 1);
                        plot3(app.UIAxes, [armPos(1,i), xFrame(1)], [armPos(2,i), xFrame(2)], [armPos(3,i), xFrame(3)], 'g-', 'LineWidth', 2);

                        % Y-axis Frames
                        yFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 2);
                        plot3(app.UIAxes, [armPos(1,i), yFrame(1)], [armPos(2,i), yFrame(2)], [armPos(3,i), yFrame(3)], 'b-', 'LineWidth', 2);

                        % Z-axis Frames
                        zFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 3);
                        plot3(app.UIAxes, [armPos(1,i), zFrame(1)], [armPos(2,i), zFrame(2)], [armPos(3,i), zFrame(3)], 'r-', 'LineWidth', 2);

                    end
                end
                pause(0.02);
            end
            % Display for debugging
            disp("The final positions are:")
            disp("       X        Y         Z")
            disp(armPos')
            thetaTotal = [rad2deg(theta1), rad2deg(theta2), rad2deg(theta3), rad2deg(theta4)];
            templateMessage = "Theta 1: %.2f\nTheta 2: %.2f\nTheta 3: %.2f\nTheta 4: %.2f\n";
            message = sprintf(templateMessage, thetaTotal(1), thetaTotal(2), thetaTotal(3), thetaTotal(4));
            app.Theta1Theta2Theta3Theta4Label.Text = message;

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %% Constants Set-Up
            penHeld = app.PenHeldCheckBox.Value;

            % Link Offset for z-axis (in cm)
            d1 = 7.7;
            % Link Length for x-axis (in cm)
            a2 = 13;
            a3 = 12.4;
            a4 = 12.6;
            if penHeld
                a4 = 25;
            end

            %% Inputs

            % 1DOF Inputs (in cm and degrees)
            x1 = app.XEditField.Value;
            y1 = app.YEditField.Value;
            z1 = app.ZEditField.Value;
            phi = app.PhiEditField.Value;
            app.Label.Value = phi;

            % Square root indicators
            s3PositiveRoot = app.PositiveS3RootCheckBox.Value; % Set to false for negative output of square root
            x2PositiveRoot = app.PositiveX2RootCheckBox.Value; % Set to false for negative output of square root

            % Graph Visualisers
            frameScale = app.FrameScaleEditField.Value;
            axisScale = app.AxisScaleEditField.Value;
            removeT1 = ~(app.DisplayT1FrameCheckBox.Value); % Set to false if you want to keep T1 label and frame

            %% Intermediate Code for working out

            % Changing phi to radians for calculations
            phi = deg2rad(phi);

            % Joint angles for 1DOF (in radians)
            theta1 = atan2(y1, x1);

            % 3DOF inputs
            x2 = (x2PositiveRoot*2-1) * sqrt(x1^2+y1^2);
            z2 = z1-d1;

            % Intermediate calculations
            c3 = ((x2-a4*cos(phi))^2 + (z2-a4*sin(phi))^2 - a3^2 - a2^2)/(2*a3*a2);

            % Conditional to ensure that c3 stays in bound
            if c3 > 1
                c3 = 1;
            end

            % Rest of the calculations
            s3 = (s3PositiveRoot*2-1) * sqrt(1-(c3^2));
            xbar = x2 - (a4*cos(phi));
            zbar = z2 - (a4*sin(phi));
            k2 = a3*s3;
            k1 = a2 + a3*c3;

            % Joint angles for 3DOF (in radians)
            theta2 = atan2(zbar, xbar) - atan2(k2, k1);
            theta3 = atan2(s3, c3);
            theta4 = phi-theta3-theta2;

            % Transformation Matrices
            T = cell(1, 5);
            T{1} = [cos(theta1), -sin(theta1), 0, 0; sin(theta1), cos(theta1), 0, 0; 0, 0, 1, d1; 0, 0, 0, 1];
            T{2} = [cos(theta2), -sin(theta2), 0, 0; 0, 0, -1, 0; sin(theta2), cos(theta2), 0, 0; 0, 0, 0, 1];
            T{3} = [cos(theta3), -sin(theta3), 0, a2; sin(theta3), cos(theta3), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];
            T{4} = [cos(theta4), -sin(theta4), 0, a3; sin(theta4), cos(theta4), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];
            T{5} = [1, 0, 0, a4; 0, 1, 0, 0; 0, 0, 1, 0; 0, 0, 0, 1];

            % Compute arm positions
            positions = cell(1, 5);
            positions{1} = [1, 0, 0, 0; 0, 1, 0, 0; 0, 0, 1, 0; 0, 0, 0, 1]; % Base case: T0
            positions{2} = T{1};
            positions{3} = T{1}*T{2};
            positions{4} = T{1}*T{2}*T{3};
            positions{5} = T{1}*T{2}*T{3}*T{4};
            positions{6} = T{1}*T{2}*T{3}*T{4}*T{5};

            %% Plotting the Robot

            % Getting all of the Positions of the arms
            armPos = zeros(3, 6); % Six points: Base + Four joints + End effector
            for i = 1:6 % 6 positions
                armPos(:, i) = positions{i}(1:3, end);
            end

            % Display for debugging
            disp("The final positions are:")
            disp("       X        Y         Z")
            disp(armPos')
            thetaTotal = [rad2deg(theta1), rad2deg(theta2), rad2deg(theta3), rad2deg(theta4)];
            templateMessage = "Theta 1: %.2f\nTheta 2: %.2f\nTheta 3: %.2f\nTheta 4: %.2f\n";
            message = sprintf(templateMessage, thetaTotal(1), thetaTotal(2), thetaTotal(3), thetaTotal(4));
            app.Theta1Theta2Theta3Theta4Label.Text = message;

            endEffector = armPos(:, 6);

            % Plotting the graph itself
            cla(app.UIAxes);
            title(app.UIAxes, '4-DOF Robot Arm');
            grid(app.UIAxes, "on");
            axis(app.UIAxes, "equal")
            axis(app.UIAxes, [-axisScale axisScale -axisScale axisScale -axisScale axisScale]);
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            zlabel(app.UIAxes,'Z');
            view(app.UIAxes, 3);
            hold(app.UIAxes,"on");
            rotate3d(app.UIAxes,"on");
            app.robotPlot = plot3(app.UIAxes, armPos(1, :), armPos(2, :), armPos(3, :), 'k-o', 'LineWidth', 2);
            app.tracePlot = plot3(app.UIAxes, endEffector(1), endEffector(2), endEffector(3), 'r-', 'LineWidth', 2);

            % Plotting the Frames and labels
            for i = 1:6

                % Conditional to remove T1 label and frame, its in flag
                if i==2 && removeT1
                    continue;
                end

                % Adding labels
                label = sprintf('T%d', (i-1));
                text(app.UIAxes, armPos(1,i), armPos(2,i), armPos(3,i), label, "VerticalAlignment","top", "HorizontalAlignment","left", "Color","k","FontSize",15)

                % X-axis Frames
                xFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 1);
                plot3(app.UIAxes, [armPos(1,i), xFrame(1)], [armPos(2,i), xFrame(2)], [armPos(3,i), xFrame(3)], 'g-', 'LineWidth', 2);

                % Y-axis Frames
                yFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 2);
                plot3(app.UIAxes, [armPos(1,i), yFrame(1)], [armPos(2,i), yFrame(2)], [armPos(3,i), yFrame(3)], 'b-', 'LineWidth', 2);

                % Z-axis Frames
                zFrame = armPos(1:3,i) + frameScale*positions{i}(1:3, 3);
                plot3(app.UIAxes, [armPos(1,i), zFrame(1)], [armPos(2,i), zFrame(2)], [armPos(3,i), zFrame(3)], 'r-', 'LineWidth', 2);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '4-DOF Robot Arm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @MovetoButtonPushed, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameScaleEditFieldLabel = uilabel(app.UIFigure);
            app.FrameScaleEditFieldLabel.Text = 'Frame Scale';
            app.FrameScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameScaleEditField.Value = 5;
            app.AxisScaleEditFieldLabel = uilabel(app.UIFigure);
            app.AxisScaleEditFieldLabel.Text = 'Axis Scale';
            app.AxisScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxisScaleEditField.Value = 50;
            app.DisplayT1FrameCheckBox = uicheckbox(app.UIFigure);
            app.DisplayT1FrameCheckBox.Text = 'Display T1 Frame';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.MoveToButton = uibutton(app.UIFigure, 'push');
            app.MoveToButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoButtonPushed, true);
            app.MoveToButton.Text = 'Move To';
            app.PositiveS3RootCheckBox = uicheckbox(app.UIFigure);
            app.PositiveS3RootCheckBox.Text = 'Positive S3 Root';
            app.PositiveX2RootCheckBox = uicheckbox(app.UIFigure);
            app.PositiveX2RootCheckBox.Text = 'Positive X2 Root';
            app.PositiveX2RootCheckBox.Value = true;
            app.Theta1Theta2Theta3Theta4Label = uilabel(app.UIFigure);
            app.Theta1Theta2Theta3Theta4Label.Text = {'Theta 1: '; 'Theta 2: '; 'Theta 3:'; 'Theta 4: '};
            app.NoofPointsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofPointsEditFieldLabel.Text = 'No. of Points';
            app.NoofPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofPointsEditField.Value = 50;
            app.Label = uieditfield(app.UIFigure, 'numeric');
            app.TraceCheckBox = uicheckbox(app.UIFigure);
            app.TraceCheckBox.Text = 'Trace';
            app.PenHeldCheckBox = uicheckbox(app.UIFigure);
            app.PenHeldCheckBox.Text = 'Pen Held';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end